classdef Audiogram < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        AudiogramApp               matlab.ui.Figure
        LabelAuthors               matlab.ui.control.Label
        ButtonHelp                 matlab.ui.control.Button
        LabelWynik                 matlab.ui.control.Label
        HyperlinkReklama           matlab.ui.control.Hyperlink
        HyperlinkSklep             matlab.ui.control.Hyperlink
        HyperlinkpLekarz           matlab.ui.control.Hyperlink
        EditFieldLeftEarLabel      matlab.ui.control.Label
        EditFieldRightEarLabel     matlab.ui.control.Label
        EditFieldScoreLabel        matlab.ui.control.Label
        LabelPremiumPorownywarka   matlab.ui.control.Label
        LabelPrzeprowadzenieTestu  matlab.ui.control.Label
        LabelAudiogram             matlab.ui.control.Label
        EditFieldScore             matlab.ui.control.NumericEditField
        EditFieldRightEar          matlab.ui.control.NumericEditField
        EditFieldLeftEar           matlab.ui.control.NumericEditField
        ButtonRozpocznij           matlab.ui.control.Button
        ButtonSlysze               matlab.ui.control.Button
        ButtonPorownaj             matlab.ui.control.Button
        ButtonPrevious             matlab.ui.control.Button
        ButtonNext                 matlab.ui.control.Button
        ImageCelebrity             matlab.ui.control.Image
        AxesAudiogram              matlab.ui.control.UIAxes
    end


    properties (Access = public)

        f_audiogram = [125 500 1000 2000 3000 4000 6000 8000 100000]; % 9 czestotliwosci audiogramu

        coef_audiogram = [1]; % wspolczynniki (wzmacniajace n*10dB); od 0dB do +140dB; Beda wypelnione po starcie programu

        offset = 0; % sztywno ustawiany Offset uszkodzenia sluchu, np. 7dB (bo 0dB na 1kHz jest glupie)

        left_ear = []; % ubytki sluchu w lewym uchu (w dB) dla 9 czestotliwosci audiogramu
        right_ear = []; % ubytki sluchu w prawym uchu (w dB) dla 9 czestotliwosci audiogramu
        left_ear_score = 0; % wynik audi0ogramu w lewym uchu [dB]
        right_ear_score = 0; % wynik audiogramu w lewym uchu [dB]
        final_score = 0; % wynik audiogramu uogolniony

        % Uniwersalny generator
        A = 0.01; % stale wzmocnienie (zeby nie ogluchnac przy testach); domyslnie bylo 1
        f = 0.0; % czestotliwosc generowanego dzwieku [Hz]
        fs = 48000; % czestotliwosc probkowania [Hz]
        t = 0.0; % probki czasowe 1 sekundy

        y = [];

        ii = 1;
        plotData;

        current_artist = 1;
        next_artist = 0;
        previous_artist = 0;
        artist = 1;
        png = '.png';

        MJackson_left_ear = [80 80 80 90 90 80 90 90 100];
        MJackson_right_ear = [10 10 20 20 20 20 10 10 10];

        FMercury_left_ear = [10 20 20 30 30 40 40 50 50];
        FMercury_right_ear = [50 50 40 40 30 30 20 20 10];

        Beyonce_left_ear = [20 30 30 30 40 40 50 50 50];
        Beyonce_right_ear = [10 20 30 30 40 50 50 60 70];
        
        Elvis_left_ear = [40 60 80 90 100 110 120 130 140];
        Elvis_right_ear = [0 0 0 40 55 70 85 100 115];

    end


    methods (Access = public)

        function [] = leftTest(app)
            %clear sound;
            for i = 1:9
                ff = app.f_audiogram(i) % czestotliwosc generowanego dzwieku [Hz]
                tt = 0:1/app.fs:1;

                while app.ii < 16 % powinno byc 14
                    app.left_ear(i) = (app.ii-1)*10; % ubytek w dB

                    %             gain = 0; % wzmocnienie dB; Do wzmacniania kolejnych
                    coeff = app.coef_audiogram(app.ii) % wspolczynnik (zwiekszajacy +dB)
                    app.y=(coeff*(app.A)*(sin(2*pi*ff*tt)));  % sygnal harmoniczny
                    left=app.y(1,1:48000);
                    noise = coeff*app.A.*rand(1, 48000); % Czy tego szumu nie przyciszyc?
                    left = [left; noise];

                    % generowanie 10 sekund w lewym kanale
                    left_10sec = [];
                    for k = 1:2
                        left_10sec = [left_10sec left];
                    end

                    % sound(left_10sec, app.fs);

                    q = audioplayer(left_10sec, app.fs);
                    playblocking(q, app.fs);

                    app.ii = app.ii + 1;

                end

                app.ii = 1;

            end

            % wynik audiogramu w lewym uchu [dB]
            % 500 Hz - element #2, 1000 Hz - #3, 2000 Hz - #4, 4000 Hz - #6
            if (abs(app.left_ear(2) - app.left_ear(4)) > 40)
                if (app.left_ear(4) < app.left_ear(6)) % Wyjatek nr 2
                    app.left_ear_score = (app.left_ear(2) + app.left_ear(3) + app.left_ear(4) + app.left_ear(6)) / 4.0;
                else % Wyjatek nr 1
                    app.left_ear_score = (app.left_ear(2) + app.left_ear(3) + app.left_ear(6)) / 3.0;
                end
            else % Przypadek typowy
                app.left_ear_score = (app.left_ear(2) + app.left_ear(3) + app.left_ear(4)) / 3.0
            end
        end

        function [] = rightTest(app)
            %clear sound;
            for i = 1:9
                ff = app.f_audiogram(i) % czestotliwosc generowanego dzwieku [Hz]
                tt = 0:1/app.fs:1;

                while app.ii < 16
                    app.right_ear(i) = (app.ii-1)*10; % ubytek w dB

                    %             gain = 0; % wzmocnienie dB; Do wzmacniania kolejnych
                    coeff = app.coef_audiogram(app.ii) % wspolczynnik (zwiekszajacy +dB)
                    app.y=(coeff*(app.A)*(sin(2*pi*ff*tt)));  % sygnal harmoniczny
                    right=app.y(1,1:48000);
                    noise = coeff*app.A.*rand(1, 48000); % Czy tego szumu nie przyciszyc?
                    right = [noise; right];

                    % generowanie 10 sekund w lewym kanale
                    right_10sec = [];
                    for k = 1:2
                        right_10sec = [right_10sec right];
                    end

                    % sound(right_10sec, app.fs);

                    q = audioplayer(right_10sec, app.fs);
                    playblocking(q, app.fs);

                    app.ii = app.ii + 1;
                end

                app.ii = 1;

            end

            app.ButtonSlysze.Enable = false;

            % wynik audiogramu w prawym uchu [dB]
            % 500 Hz - element #2, 1000 Hz - #3, 2000 Hz - #4, 4000 Hz - #6
            if (abs(app.right_ear(2) - app.right_ear(4)) > 40)
                if (app.right_ear(4) < app.right_ear(6)) % Wyjatek nr 2
                    app.right_ear_score = (app.right_ear(2) + app.right_ear(3) + app.right_ear(4) + app.right_ear(6)) / 4.0;
                else % Wyjatek nr 1
                    app.right_ear_score = (app.right_ear(2) + app.right_ear(3) + app.right_ear(6)) / 3.0;
                end
            else % Przypadek typowy
                app.right_ear_score = (app.right_ear(2) + app.right_ear(3) + app.right_ear(4)) / 3.0
            end

            app.score;

        end

    end % method

    methods (Access = private)

        % 0 - lewy kanal, 1 - prawy kanal
        function [] = calibration(app, channel)

            app.fs=48000; % f probkowania
            app.f=1000; % f generowanego dzwieku
            app.t=0:1/app.fs:1; % probki czasowe
            app.A=0.01; % domyslnie bylo 1
            app.y=app.A*sin(2*pi*app.f*app.t);  % sygnal harmoniczny
            Py=10*log10(sum(app.y.^2)); % moc akustyczna
            coef=10.^(5/20); % wspolczynnik (zwiekszajacy +dB)
            yy=app.y.*coef; % wzmocnienie sygnalu o +5dB
            Pyy=10*log10(sum(yy.^2)); % moc akustyczna +5dB
            test=[]; % dzwiek testowy do kalibracji

            % generowanie 2*10 sekund sygnalu testowego do kalibracji
            if (channel == 0) % kanal lewy
                left=[app.y(1,1:48000) yy(1,1:48000)];
                noise = coef*app.A.*rand(1, 96000); % Czy tego szumu nie przyciszyc?
                left = [left; noise];
                for i=1:10
                    test=[test left];
                end
            else % kanal prawy
                right=[app.y(1,1:48000) yy(1,1:48000)];
                noise = coef*app.A.*rand(1, 96000);
                right = [noise; right];
                for i=1:10
                    test=[test right];
                end
            end

            sound(test, app.fs); %dzwiek testowy
            h=questdlg('Kliknij, gdy będziesz słyszeć tylko jeden z dwóch tonów', ...
                'Kalibracja','OK','OK');
            switch h
                case 'OK'
                    %'OK' code here
                    clear("sound");
                case ''
                    %abort code
                otherwise
            end
        end

        function [] = score(app)

            % Wykres ucha lewego
            app.plotData = plot(app.AxesAudiogram, app.left_ear);
            app.plotData.Color = 'blue';
            set(app.plotData, 'LineWidth', 2);
            hold(app.AxesAudiogram, 'on');
            app.EditFieldLeftEar.Value = app.left_ear_score;

            % Wykres ucha prawego
            app.plotData = plot(app.AxesAudiogram, app.right_ear);
            app.plotData.Color = 'red';
            set(app.plotData, 'LineWidth', 2);
            hold(app.AxesAudiogram, 'off');
            app.EditFieldRightEar.Value = app.right_ear_score;
            
            app.EditFieldLeftEar.Visible = true;
            app.EditFieldLeftEarLabel.Visible = true;
            app.EditFieldRightEar.Visible = true;
            app.EditFieldRightEarLabel.Visible = true;
            app.EditFieldScore.Visible = true;
            app.EditFieldScoreLabel.Visible = true;
            app.LabelAudiogram.Visible = false;
            app.AxesAudiogram.Visible = true;
            app.LabelWynik.Visible = true;


            % Wynik finalny audiogramu
            if (abs(app.right_ear_score - app.left_ear_score) > 25)
                app.final_score = 5;
            end
            if (app.right_ear_score < app.left_ear_score)
                app.final_score = app.final_score + app.right_ear_score;
            else
                app.final_score = app.final_score + app.left_ear_score;
            end
            app.EditFieldScore.Value = app.final_score;

            % legenda
            app.plotData = legend(app.AxesAudiogram, {'Ucho lewe','Ucho prawe'}, 'Location','eastoutside');

            % Okienko z informacja
            if (app.final_score > 120)
                text = ('Calkowita gluchota');
                app.LabelWynik.Text = "Całkowita głuchota - możesz nie słyszeć startującego samolotu, wybuchów. Konieczna wizyta u lekarza.";
            elseif (app.final_score > 90)
                text = ('Glebokie uszkodzenie sluchu');
                app.LabelWynik.Text = "Głębokie uszkodzenie słuchu - możesz nie słyszeć muzyki w dyskotece, ruchu ulicznego. Konieczna wizyta u lekarza.";                
            elseif (app.final_score > 70)
                text = ('Znaczne uszkodzenie sluchu');
                app.LabelWynik.Text = "Znaczne uszkodzenie słuchu - możesz nie słyszeć odkurzacza i typowych odgłosów w domu i biurze. Konieczna wizyta u lekarza.";                
            elseif (app.final_score > 40)
                text = ('Umiarkowane uszkodzenie sluchu');
                app.LabelWynik.Text = "Umiarkowane uszkodzenie słuchu - możesz mieć problem ze zrozumieniem spokojnej mowy ludzkiej. Zalecana wizyta u lekarza.";            
            elseif (app.final_score > 20)
                text = ('Lekkie uszkodzenie sluchu');
                app.LabelWynik.Text = "Lekkie uszkodzenie słuchu - możesz nie słyszeć szeptu, szelestu liści. Zalecana wizyta u lekarza.";                
            else
                text = ('Sluch w normie');
                app.LabelWynik.Text = "Słuch w normie - nie powinieneś odczuwać problemów. Niniejszy test nie zastępuje wizyty u lekarza, ani nie zwalnia z profilaktyki.";                

            end

            h=questdlg(text, 'Wynik audiogramu', 'OK', 'OK');
            switch h
                case 'OK'
                    %'OK' code here
                case ''
                    %abort code
                otherwise
            end
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

            % Wypelnienie tablicy wspolczynnikow wzmocnienia
            for i = 1:14 %wypelnienie
                app.coef_audiogram(i+1) = 10.^(i*10/20);
            end

            % Uniwersalny generator - CZY TEN KAWALEK KODU JEST JESZCZE
            % POTRZEBNY? TO CHYBA TYLKO MOJA TEMPLATKA
            app.f = app.f_audiogram(1); % czestotliwosc generowanego dzwieku [Hz]
            app.t = 0:1/app.fs:1;
            %             gain = 0; % wzmocnienie dB; Do wzmacniania kolejnych
            coef = app.coef_audiogram(1); % wspolczynnik (zwiekszajacy +dB)
            app.y=coef*app.A*sin(2*pi*app.f*app.t);  % sygnal harmoniczny

        end

        % Button pushed function: ButtonRozpocznij
        function CallbackRozpocznij(app, event)
       
            delete(app.plotData);       %czyszczenie legendy
            cla(app.AxesAudiogram);     %czyszczenie wykresu
            
            app.EditFieldLeftEar.Visible = false;
            app.EditFieldRightEarLabel.Visible = false;
            app.EditFieldRightEar.Visible = false;
            app.EditFieldRightEarLabel.Visible = false;
            app.EditFieldScore.Visible = false;
            app.EditFieldScoreLabel.Visible = false;
            app.LabelWynik.Visible = false;
            
            app.LabelAudiogram.Visible = true;
            app.AxesAudiogram.Visible = false;

            app.ImageCelebrity.Visible = false;
            app.ButtonPorownaj.Visible = false;
            app.ButtonNext.Visible = false;
            app.ButtonPrevious.Visible = false;
            app.LabelPremiumPorownywarka.Visible = false;
            
            hold(app.AxesAudiogram, 'off');

            
            % Kanał lewy - wykorzystac generator i 2 petle (czestotliwosci i wzmocnienia)
            app.ButtonRozpocznij.Enable = false;
            app.calibration(0);
            app.ButtonSlysze.Enable = true;
            app.leftTest;
            app.ButtonSlysze.Enable = false;
            app.calibration(1);
            app.ButtonSlysze.Enable = true;
            app.rightTest;
            app.ButtonRozpocznij.Enable = true;

            % Wyswietlenie panelu Premium - porównarki
            app.ImageCelebrity.Visible = true;
            app.ButtonPorownaj.Visible = true;
            app.ButtonNext.Visible = true;
            app.ButtonPrevious.Visible = true;
            app.LabelPremiumPorownywarka.Visible = true;

        end

        % Button pushed function: ButtonSlysze
        function CallbackSlysze(app, event)
            app.ii = 16; % zatrzymanie petli dla glosnosci
        end

        % Button pushed function: ButtonPorownaj
        function CallbackPorownaj(app, event)

            % Wykres ucha lewego
            app.plotData = plot(app.AxesAudiogram, app.left_ear);
            app.plotData.Color = 'blue';
            set(app.plotData, 'LineWidth', 2);
            hold(app.AxesAudiogram, 'on');

            % Wykres ucha prawego
            app.plotData = plot(app.AxesAudiogram, app.right_ear);
            app.plotData.Color = 'red';
            set(app.plotData, 'LineWidth', 2);
            hold(app.AxesAudiogram, 'on');

            if (app.artist == 1)
                app.current_artist = 1;
                % Wykres ucha lewego
                app.plotData = plot(app.AxesAudiogram, app.MJackson_left_ear, "LineStyle","--");
                app.plotData.Color = 'blue';
                set(app.plotData, 'LineWidth', 2);
                hold(app.AxesAudiogram, 'on');

                % Wykres ucha prawego
                app.plotData = plot(app.AxesAudiogram, app.MJackson_right_ear, "LineStyle","--");
                app.plotData.Color = 'red';
                set(app.plotData, 'LineWidth', 2);
                hold(app.AxesAudiogram, 'off');
                app.plotData = legend(app.AxesAudiogram, {'Ucho lewe','Ucho prawe', 'Ucho lewe artysty','Ucho prawe artysty' }, 'Location','eastoutside');
            elseif (app.artist == 2)
                app.current_artist = 2;
                % Wykres ucha lewego
                app.plotData = plot(app.AxesAudiogram, app.FMercury_left_ear, "LineStyle","--");
                app.plotData.Color = 'blue';
                set(app.plotData, 'LineWidth', 2);
                hold(app.AxesAudiogram, 'on');

                % Wykres ucha prawego
                app.plotData = plot(app.AxesAudiogram, app.FMercury_right_ear, "LineStyle","--");
                app.plotData.Color = 'red';
                set(app.plotData, 'LineWidth', 2);
                hold(app.AxesAudiogram, 'off');
                app.plotData = legend(app.AxesAudiogram, {'Ucho lewe','Ucho prawe', 'Ucho lewe artysty','Ucho prawe artysty' }, 'Location','eastoutside');
            elseif (app.artist == 3)
                app.current_artist = 3;
                % Wykres ucha lewego
                app.plotData = plot(app.AxesAudiogram, app.Beyonce_left_ear, "LineStyle","--");
                app.plotData.Color = 'blue';
                set(app.plotData, 'LineWidth', 2);
                hold(app.AxesAudiogram, 'on');

                % Wykres ucha prawego
                app.plotData = plot(app.AxesAudiogram, app.Beyonce_right_ear, "LineStyle","--");
                app.plotData.Color = 'red';
                set(app.plotData, 'LineWidth', 2);
                hold(app.AxesAudiogram, 'off');
                app.plotData = legend(app.AxesAudiogram, {'Ucho lewe','Ucho prawe', 'Ucho lewe artysty','Ucho prawe artysty' }, 'Location','eastoutside');
            elseif (app.artist == 4)
                app.current_artist = 4;
                % Wykres ucha lewego
                app.plotData = plot(app.AxesAudiogram, app.Elvis_left_ear, "LineStyle","--");
                app.plotData.Color = 'blue';
                set(app.plotData, 'LineWidth', 2);
                hold(app.AxesAudiogram, 'on');

                % Wykres ucha prawego
                app.plotData = plot(app.AxesAudiogram, app.Elvis_right_ear, "LineStyle","--");
                app.plotData.Color = 'red';
                set(app.plotData, 'LineWidth', 2);
                hold(app.AxesAudiogram, 'off');
                app.plotData = legend(app.AxesAudiogram, {'Ucho lewe','Ucho prawe', 'Ucho lewe artysty','Ucho prawe artysty' }, 'Location','eastoutside');
            end

        end

        % Button pushed function: ButtonNext
        function CallbackNext(app, event)

            app.next_artist = app.current_artist + 1;
            if (app.next_artist < 5)
                app.artist = app.next_artist;
                chrArtist = int2str(app.next_artist);
                app.ImageCelebrity.ImageSource = strcat(chrArtist, app.png);
                app.current_artist = app.artist;
            end

        end

        % Button pushed function: ButtonPrevious
        function CallbackPrevious(app, event)

            app.previous_artist = app.current_artist - 1;
            if (app.previous_artist > 0)
                app.artist = app.previous_artist;
                chrArtist = int2str(app.previous_artist);
                app.ImageCelebrity.ImageSource = strcat(chrArtist, app.png);
                app.current_artist = app.artist;
            end

        end

        % Button pushed function: ButtonHelp
        function ButtonHelpPushed(app, event)
            msgbox("Procedura testowa:" + newline + ...
                "Test należy wykonać z użyciem słuchawek stereo z dwoma sprawnymi kanałami, i w miarę możliwości równomierną charakterystyką przenoszenia w zakesie 125 - 10000 Hz" + newline + ...
                "1. Kalibracja lewego kanału - należy regulować głośność systemową i/lub słuchawek oraz kliknąć przycisk OK, gdy będzie słyszeć się jedynie jeden z dwóch tonów w lewym kanale." + newline + ...
                "2. Test lewego ucha - należy klikać przycisk SŁYSZĘ zawsze po usłyszeniu tonu (nie szumu)." + newline + ...
                "3. Kalibracja prawego kanału - analogicznie jak dla lewego." + newline + ...
                "4. Test prawego ucha - analogicznie jak dla lewego.");
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.AudiogramApp = uifigure('Visible', 'off');
            app.AudiogramApp.Name = 'TURBO AUDIOGRAM';
            app.AxesAudiogram = uiaxes(app.AudiogramApp);
            title(app.AxesAudiogram, {'Wynik Audiogramu'; ''})
            xlabel(app.AxesAudiogram, 'Częstotliwość [Hz]')
            ylabel(app.AxesAudiogram, 'Ubytek słuchu [dB]')
            zlabel(app.AxesAudiogram, 'Z')
            app.AxesAudiogram.XLim = [0.5 9.5];
            app.AxesAudiogram.YLim = [-10 150];
            app.AxesAudiogram.YDir = 'reverse';
            app.AxesAudiogram.XAxisLocation = 'top';
            app.AxesAudiogram.XTick = [0 1 2 3 4 5 6 7 8 9];
            app.AxesAudiogram.XTickLabel = {'0'; '125'; '500'; '1000'; '2000'; '3000'; '4000'; '6000'; '8000'; '10000'};
            app.AxesAudiogram.YTick = [-10 0 20 40 60 80 100 120 140];
            app.AxesAudiogram.YTickLabel = {'-10'; '0'; '20'; '40'; '60'; '80'; '100'; '120'; '140'};
            app.AxesAudiogram.Visible = 'off';
            app.ImageCelebrity = uiimage(app.AudiogramApp);
            app.ImageCelebrity.Visible = 'off';
            app.ImageCelebrity.ImageSource = '1.png';
            app.ButtonNext = uibutton(app.AudiogramApp, 'push');
            app.ButtonNext.ButtonPushedFcn = createCallbackFcn(app, @CallbackNext, true);
            app.ButtonNext.Visible = 'off';
            app.ButtonNext.Text = 'Następny';
            app.ButtonPrevious = uibutton(app.AudiogramApp, 'push');
            app.ButtonPrevious.ButtonPushedFcn = createCallbackFcn(app, @CallbackPrevious, true);
            app.ButtonPrevious.Visible = 'off';
            app.ButtonPrevious.Text = {'Poprzedni'; ''};
            app.ButtonPorownaj = uibutton(app.AudiogramApp, 'push');
            app.ButtonPorownaj.ButtonPushedFcn = createCallbackFcn(app, @CallbackPorownaj, true);
            app.ButtonPorownaj.Visible = 'off';
            app.ButtonPorownaj.Text = 'Porównaj';
            app.ButtonSlysze = uibutton(app.AudiogramApp, 'push');
            app.ButtonSlysze.ButtonPushedFcn = createCallbackFcn(app, @CallbackSlysze, true);
            app.ButtonSlysze.Enable = 'off';
            app.ButtonSlysze.Text = 'Słyszę';
            app.ButtonRozpocznij = uibutton(app.AudiogramApp, 'push');
            app.ButtonRozpocznij.ButtonPushedFcn = createCallbackFcn(app, @CallbackRozpocznij, true);
            app.ButtonRozpocznij.Text = 'Rozpocznij test';
            app.EditFieldLeftEar = uieditfield(app.AudiogramApp, 'numeric');
            app.EditFieldLeftEar.ValueDisplayFormat = '%.2f';
            app.EditFieldLeftEar.Editable = 'off';
            app.EditFieldLeftEar.Visible = 'off';
            app.EditFieldRightEar = uieditfield(app.AudiogramApp, 'numeric');
            app.EditFieldRightEar.ValueDisplayFormat = '%.2f';
            app.EditFieldRightEar.Editable = 'off';
            app.EditFieldRightEar.Visible = 'off';
            app.EditFieldScore = uieditfield(app.AudiogramApp, 'numeric');
            app.EditFieldScore.ValueDisplayFormat = '%.2f';
            app.EditFieldScore.Editable = 'off';
            app.EditFieldScore.Visible = 'off';
            app.LabelAudiogram = uilabel(app.AudiogramApp);
            app.LabelAudiogram.Text = 'TURBO AUDIOGRAM ';
            app.LabelPrzeprowadzenieTestu = uilabel(app.AudiogramApp);
            app.LabelPrzeprowadzenieTestu.Text = 'Przeprowadzenie testu';
            app.LabelPremiumPorownywarka = uilabel(app.AudiogramApp);
            app.LabelPremiumPorownywarka.Visible = 'off';
            app.LabelPremiumPorownywarka.Text = 'JAKĄ GWIAZDĄ JESTEŚ?';
            app.EditFieldScoreLabel = uilabel(app.AudiogramApp);
            app.EditFieldScoreLabel.Visible = 'off';
            app.EditFieldScoreLabel.Text = 'Wynik [dB]';
            app.EditFieldRightEarLabel = uilabel(app.AudiogramApp);
            app.EditFieldRightEarLabel.Visible = 'off';
            app.EditFieldRightEarLabel.Text = 'Prawe ucho [dB]';
            app.EditFieldLeftEarLabel = uilabel(app.AudiogramApp);
            app.EditFieldLeftEarLabel.Visible = 'off';
            app.EditFieldLeftEarLabel.Text = 'Lewe ucho [dB]';
            app.HyperlinkpLekarz = uihyperlink(app.AudiogramApp);
            app.HyperlinkpLekarz.URL = 'https://t4.ftcdn.net/jpg/04/26/99/95/360_F_426999565_AJdlhzyu9f3kL5T6cXlZJfkg4AoCDc6E.jpg';
            app.HyperlinkpLekarz.Text = 'Kliknij, by umówić się na wizytę u rekomendowanego lekarza!';
            app.HyperlinkSklep = uihyperlink(app.AudiogramApp);
            app.HyperlinkSklep.URL = 'https://a.allegroimg.com/s512/03e6c1/95be272d4c56b76b9b4c5919dc73/APARAT-SLUCHOWY-REDUKCJA-SZUMOW-WZMACNIACZ-SLUCHU-Waga-produktu-z-opakowaniem-jednostkowym-0-2-kg';
            app.HyperlinkSklep.Text = 'Kliknij, by zamówić najlepszy aparat słuchowy!';
            app.HyperlinkReklama = uihyperlink(app.AudiogramApp);
            app.HyperlinkReklama.URL = 'https://ocdn.eu/images/pulscms/ZDI7MDA_/0609f5ee8a997b10ae13f8497a4da233.jpeg';
            app.HyperlinkReklama.Text = 'Kliknij, by umieścić tu swoją reklamę!';
            app.LabelWynik = uilabel(app.AudiogramApp);
            app.LabelWynik.Visible = 'off';
            app.LabelWynik.Text = 'Całkowita głuchota - możesz nie słyszeć startującego samolotu, wybuchów. Konieczna wizyta u lekarza.';
            app.ButtonHelp = uibutton(app.AudiogramApp, 'push');
            app.ButtonHelp.ButtonPushedFcn = createCallbackFcn(app, @ButtonHelpPushed, true);
            app.ButtonHelp.Icon = 'questionmark.png';
            app.ButtonHelp.Text = '';
            app.LabelAuthors = uilabel(app.AudiogramApp);
            app.LabelAuthors.Text = '© Jakub Kołton & Kacper Janowski, 2021';
            % Show the figure after all components are created
            app.AudiogramApp.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Audiogram

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.AudiogramApp)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.AudiogramApp)
        end
    end
end